classdef DebugApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        Line2Gauge_9         matlab.ui.control.LinearGauge
        Line2Gauge_8         matlab.ui.control.LinearGauge
        Line2Gauge_7         matlab.ui.control.LinearGauge
        Panel                matlab.ui.container.Panel
        Image2               matlab.ui.control.Image
        Image_4              matlab.ui.control.Image
        Image_3              matlab.ui.control.Image
        Label_3              matlab.ui.control.Label
        Image_2              matlab.ui.control.Image
        Label_2              matlab.ui.control.Label
        Image                matlab.ui.control.Image
        D21Label_3           matlab.ui.control.Label
        Line2Gauge_4         matlab.ui.control.LinearGauge
        TimePanel            matlab.ui.container.Panel
        add5mButton          matlab.ui.control.Button
        add10mButton         matlab.ui.control.Button
        add30mButton         matlab.ui.control.Button
        Label_sysTime        matlab.ui.control.Label
        Label                matlab.ui.control.Label
        trainICONexample     matlab.ui.control.Image
        TrainProgressPanel   matlab.ui.container.Panel
        TabGroup             matlab.ui.container.TabGroup
        DTab                 matlab.ui.container.Tab
        GridLayout           matlab.ui.container.GridLayout
        SilderD25            matlab.ui.control.LinearGauge
        D25Label             matlab.ui.control.Label
        trainICONexample_5   matlab.ui.control.Image
        SilderD24            matlab.ui.control.LinearGauge
        SilderD23            matlab.ui.control.LinearGauge
        SilderD21            matlab.ui.control.LinearGauge
        SilderD26            matlab.ui.control.LinearGauge
        D26Label             matlab.ui.control.Label
        trainICONexample_6   matlab.ui.control.Image
        SilderD22            matlab.ui.control.LinearGauge
        D22Label             matlab.ui.control.Label
        trainICONexample_4   matlab.ui.control.Image
        D24Label             matlab.ui.control.Label
        D21Label_2           matlab.ui.control.Label
        trainICONexample_3   matlab.ui.control.Image
        trainICONexample_2   matlab.ui.control.Image
        D23Label             matlab.ui.control.Label
        trainICOND21         matlab.ui.control.Image
        GTab                 matlab.ui.container.Tab
        GridLayout2          matlab.ui.container.GridLayout
        SilderG26            matlab.ui.control.LinearGauge
        G26Label_2           matlab.ui.control.Label
        trainICONexample_12  matlab.ui.control.Image
        SilderG24            matlab.ui.control.LinearGauge
        G24Label             matlab.ui.control.Label
        trainICONexample_11  matlab.ui.control.Image
        SilderG22            matlab.ui.control.LinearGauge
        G22Label             matlab.ui.control.Label
        trainICONexample_10  matlab.ui.control.Image
        SilderG25            matlab.ui.control.LinearGauge
        G25Label             matlab.ui.control.Label
        trainICONexample_9   matlab.ui.control.Image
        G23Label             matlab.ui.control.Label
        SilderG23            matlab.ui.control.LinearGauge
        SilderG21            matlab.ui.control.LinearGauge
        G21Label_2           matlab.ui.control.Label
        trainICONexample_8   matlab.ui.control.Image
        trainICONexample_7   matlab.ui.control.Image
        DTab_2               matlab.ui.container.Tab
        GridLayout3          matlab.ui.container.GridLayout
        SilderD18            matlab.ui.control.LinearGauge
        SilderD16            matlab.ui.control.LinearGauge
        SilderD11            matlab.ui.control.LinearGauge
        SilderD12            matlab.ui.control.LinearGauge
        SilderD14            matlab.ui.control.LinearGauge
        SilderD17            matlab.ui.control.LinearGauge
        SilderD15            matlab.ui.control.LinearGauge
        SilderD13            matlab.ui.control.LinearGauge
        trainICONexample_19  matlab.ui.control.Image
        trainICONexample_20  matlab.ui.control.Image
        trainICONexample_18  matlab.ui.control.Image
        trainICONexample_17  matlab.ui.control.Image
        trainICONexample_16  matlab.ui.control.Image
        trainICONexample_15  matlab.ui.control.Image
        trainICONexample_14  matlab.ui.control.Image
        D18Label             matlab.ui.control.Label
        D16Label             matlab.ui.control.Label
        D14Label             matlab.ui.control.Label
        D12Label             matlab.ui.control.Label
        D15Label             matlab.ui.control.Label
        D17Label             matlab.ui.control.Label
        D13Label             matlab.ui.control.Label
        D11Label_2           matlab.ui.control.Label
        trainICONexample_13  matlab.ui.control.Image
        GTab_2               matlab.ui.container.Tab
        GridLayout4          matlab.ui.container.GridLayout
        SilderG11            matlab.ui.control.LinearGauge
        SilderG16            matlab.ui.control.LinearGauge
        G16Label             matlab.ui.control.Label
        SilderG14            matlab.ui.control.LinearGauge
        trainICONexample_26  matlab.ui.control.Image
        trainICONexample_25  matlab.ui.control.Image
        G14Label             matlab.ui.control.Label
        SilderG12            matlab.ui.control.LinearGauge
        G12Label             matlab.ui.control.Label
        trainICONexample_24  matlab.ui.control.Image
        SilderG15            matlab.ui.control.LinearGauge
        trainICONexample_23  matlab.ui.control.Image
        G15Label             matlab.ui.control.Label
        SilderG13            matlab.ui.control.LinearGauge
        G13Label_2           matlab.ui.control.Label
        trainICONexample_22  matlab.ui.control.Image
        trainICONexample_21  matlab.ui.control.Image
        G11Label_2           matlab.ui.control.Label
        add1hButton          matlab.ui.control.Button
        minus10mButton_2     matlab.ui.control.Button
        MapPanel             matlab.ui.container.Panel
        Line2Gauge_2         matlab.ui.control.LinearGauge
    end

    
    properties (Access = private)
        mapFig; % Description
        gx;
      
    end
    
    properties (Access = public)
        trainDispatch;
        displayedTrains;
    end
    
    methods (Access = public)
        
        function display_update_systime(app)
            app.Label_sysTime.Text=app.trainDispatch.SysTimeDisplay;
        end
        
        function updateTrainUI(app)
            for i = 1:length(app.trainDispatch.Trains)
                train=app.trainDispatch.Trains(i);
                app.getTrainSilder(train).Value=app.computeSliderValue(train);
               
            end

        end
    end
    
    methods (Access = private)
        
        function drawMap(app)

            latshanghaiHQ = 31.19590;
            lonshanghaiHQ = 121.34113;
            
            latnanjingS=32.006161;
            lonnanjingS=118.778572;

            latchangzhouN=31.853495;
            lonchangzhouN=119.955276;

            latsuzhouN=31.421701;
            lonsuzhouN=120.643127;

            lonjiaxingS=120.799957;
            latjiaxingS=30.69058;

            lonliyang=119.4846;
            latliyang=31.41586;

            lonhuzhou=120.023216;
            lathuzhou=30.862961;

            lonhangzhouE=120.212997;
            lathangzhouE=30.29133;

            mapCorx=[latnanjingS latchangzhouN latsuzhouN latshanghaiHQ latjiaxingS lathangzhouE lathuzhou latliyang latnanjingS];
            mapCory=[lonnanjingS lonchangzhouN lonsuzhouN lonshanghaiHQ lonjiaxingS lonhangzhouE lonhuzhou lonliyang lonnanjingS];

            
            geoplot(app.gx,mapCorx, mapCory ,'-pentagram',"LineWidth",1)
            geolimits(app.gx,[30 32.2],[118.4 122])
            %geolimits('auto') 
            geobasemap(app.gx,'topographic')
            
            
        end

        function silder=getTrainSilder(app,train)
            silder=0;
            if strcmp(train.trainCode,"D23")
                silder=app.SilderD23;
            elseif strcmp(train.trainCode,"D21")
                silder=app.SilderD21;
            elseif strcmp(train.trainCode,"D24")
                silder=app.SilderD24;
            elseif strcmp(train.trainCode,"D22")
                silder=app.SilderD22;
            elseif strcmp(train.trainCode,"D25")
                silder=app.SilderD25;
            elseif strcmp(train.trainCode,"D26")
                silder=app.SilderD26;
            elseif strcmp(train.trainCode,"G21")
                silder=app.SilderG21;
            elseif strcmp(train.trainCode,"G23")
                silder=app.SilderG23;
            elseif strcmp(train.trainCode,"G25")
                silder=app.SilderG25;
            elseif strcmp(train.trainCode,"G22")
                silder=app.SilderG22;
            elseif strcmp(train.trainCode,"G24")
                silder=app.SilderG24;
            elseif strcmp(train.trainCode,"G26")
                silder=app.SilderG26;
            elseif strcmp(train.trainCode,"D11")
                silder=app.SilderD11;
            elseif strcmp(train.trainCode,"D12")
                silder=app.SilderD12;
            elseif strcmp(train.trainCode,"D13")
                silder=app.SilderD13;
            elseif strcmp(train.trainCode,"D14")
                silder=app.SilderD14;
            elseif strcmp(train.trainCode,"D15")
                silder=app.SilderD15;
            elseif strcmp(train.trainCode,"D16")
                silder=app.SilderD16;
            elseif strcmp(train.trainCode,"D17")
                silder=app.SilderD17;
            elseif strcmp(train.trainCode,"D18")
                silder=app.SilderD18;
            elseif strcmp(train.trainCode,"G11")
                silder=app.SilderG11;
            elseif strcmp(train.trainCode,"G12")
                silder=app.SilderG12;
            elseif strcmp(train.trainCode,"G13")
                silder=app.SilderG13;
            elseif strcmp(train.trainCode,"G14")
                silder=app.SilderG14;
            elseif strcmp(train.trainCode,"G15")
                silder=app.SilderG15;
            elseif strcmp(train.trainCode,"G16")
                silder=app.SilderG16;
            else
                disp("Error! 没法拿到火车的进度条!")
            end
        
            
        end

        function silderValue=computeSliderValue(app,train)

            silderValue=0;
            if strcmp(train.status,"RUNNING")
                startFrom=0;
                if train.lineType==1
                    %从哪个站点开始偏移
                    
                    startStation=train.remainingStations(1).stationName;
                    if strcmp(startStation,"南京南")
                        startFrom=1;
                    end
                    if strcmp(startStation,"溧阳")
                        startFrom=2;
                    end
                    if strcmp(startStation,"湖州")
                        startFrom=3;
                    end
                    if strcmp(startStation,"杭州东")
                        startFrom=4;
                    end
                    if train.lineDirection==1
                        startFrom=5-startFrom;
                    end
                    
                end
                if train.lineType==2
                    %长线
                    startFrom=0;
                    if train.DorG==1
                        startStation=train.remainingStations(1).stationName;
                        if strcmp(startStation,"南京南")
                            startFrom=1;
                        end
                        if strcmp(startStation,"常州北")
                            startFrom=2;
                        end
                        if strcmp(startStation,"苏州北")
                            startFrom=3;
                        end
                        if strcmp(startStation,"上海虹桥")
                            startFrom=4;
                        end
                        if strcmp(startStation,"嘉兴南")
                            startFrom=5;
                        end
                        if strcmp(startStation,"杭州东")
                            startFrom=6;
                        end
                        if train.lineDirection==0
                            startFrom=7-startFrom;
                        end
                    else
                        startFrom=1;
                    end

                end
                %计算两个相邻站点之间的百分比
                    startTime=train.remainingStations(1).departureTime;
                    endTime=train.remainingStations(2).arrivalTime;
                    nowTime=app.trainDispatch.SysTime;
                    offset=0;
                    
                    if nowTime>startTime
                        %确保列车没有停靠
                        if train.DorG==1
                            offset=(nowTime-startTime)/(endTime-startTime);
                        else
                            offset=(nowTime-startTime)*(3+2*(train.lineType-1))/(endTime-startTime);
                        end
                    end
                    silderValue=startFrom+offset;
            end
            if strcmp(train.status,"STOP")
                if train.lineType==1
                    silderValue=4;
                end
                if train.lineType==2
                    silderValue=6;
                end
            end
            
        end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            %app.mapFig=uifigure(app.UIFigure);
            %app.gx = geoaxes(app.mapFig);
            app.gx = geoaxes(app.MapPanel);
            drawMap(app);
            %app.display_update_systime();
            keySet = {'Jan','Feb','Mar','Apr'};
            valueSet = [327.2 368.2 197.6 178.4];
            app.displayedTrains = containers.Map(keySet,valueSet)

            %app.displayedTrains = containers.Map

            
        end

        % Button pushed function: add10mButton
        function add10mButtonPushed(app, event)
            "加10分钟时间"
            app.trainDispatch.changeSysTime(minutes(10));
            app.updateTrainUI();
        end

        % Button pushed function: add1hButton
        function add1hButtonPushed(app, event)
            "加1小时时间"
            app.trainDispatch.changeSysTime(hours(1));
            app.updateTrainUI();
        end

        % Button pushed function: minus10mButton_2
        function minus10mButton_2Pushed(app, event)
            "减少10分钟"
            app.trainDispatch.changeSysTime(-minutes(10));
            app.updateTrainUI();
        end

        % Button pushed function: add5mButton
        function add5mButtonPushed(app, event)
            "加5分钟时间"
            app.trainDispatch.changeSysTime(minutes(5));
            app.updateTrainUI();
        end

        % Button pushed function: add30mButton
        function add30mButtonPushed(app, event)
            "加30分钟时间"
            app.trainDispatch.changeSysTime(minutes(30));
            app.updateTrainUI();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Line2Gauge_2 = uigauge(app.UIFigure, 'linear');
            app.Line2Gauge_2.Limits = [1 4];
            app.Line2Gauge_2.MajorTicks = [1 2 3 4];
            app.Line2Gauge_2.MajorTickLabels = {'南京南', '溧阳', '湖州', '杭州东'};
            app.MapPanel = uipanel(app.UIFigure);
            app.MapPanel.Title = 'Map';
            app.minus10mButton_2 = uibutton(app.UIFigure, 'push');
            app.minus10mButton_2.ButtonPushedFcn = createCallbackFcn(app, @minus10mButton_2Pushed, true);
            app.minus10mButton_2.Visible = 'off';
            app.minus10mButton_2.Text = {'- 10 min'; ''};
            app.add1hButton = uibutton(app.UIFigure, 'push');
            app.add1hButton.ButtonPushedFcn = createCallbackFcn(app, @add1hButtonPushed, true);
            app.add1hButton.Visible = 'off';
            app.add1hButton.Text = {'+ 1 hour'; ''};
            app.TrainProgressPanel = uipanel(app.UIFigure);
            app.TrainProgressPanel.Title = 'TrainProgress';
            app.TabGroup = uitabgroup(app.TrainProgressPanel);
            app.DTab = uitab(app.TabGroup);
            app.DTab.Title = '短线D';
            app.GridLayout = uigridlayout(app.DTab);
            app.trainICOND21 = uiimage(app.GridLayout);
            app.trainICOND21.ImageSource = 'trainIcon.png';
            app.D23Label = uilabel(app.GridLayout);
            app.D23Label.Text = 'D23';
            app.trainICONexample_2 = uiimage(app.GridLayout);
            app.trainICONexample_2.ImageSource = 'trainIcon.png';
            app.trainICONexample_3 = uiimage(app.GridLayout);
            app.trainICONexample_3.ImageSource = 'trainIcon.png';
            app.D21Label_2 = uilabel(app.GridLayout);
            app.D21Label_2.Text = 'D21';
            app.D24Label = uilabel(app.GridLayout);
            app.D24Label.Text = 'D24';
            app.trainICONexample_4 = uiimage(app.GridLayout);
            app.trainICONexample_4.ImageSource = 'trainIcon.png';
            app.D22Label = uilabel(app.GridLayout);
            app.D22Label.Text = 'D22';
            app.SilderD22 = uigauge(app.GridLayout, 'linear');
            app.SilderD22.Limits = [1 4];
            app.SilderD22.MajorTicks = [1 2 3 4];
            app.SilderD22.MajorTickLabels = {'南京南', '溧阳', '湖州', '杭州东'};
            app.trainICONexample_6 = uiimage(app.GridLayout);
            app.trainICONexample_6.ImageSource = 'trainIcon.png';
            app.D26Label = uilabel(app.GridLayout);
            app.D26Label.Text = 'D26';
            app.SilderD26 = uigauge(app.GridLayout, 'linear');
            app.SilderD26.Limits = [1 4];
            app.SilderD26.MajorTicks = [1 2 3 4];
            app.SilderD26.MajorTickLabels = {'南京南', '溧阳', '湖州', '杭州东'};
            app.SilderD21 = uigauge(app.GridLayout, 'linear');
            app.SilderD21.Limits = [1 4];
            app.SilderD21.MajorTicks = [1 2 3 4];
            app.SilderD21.MajorTickLabels = {'杭州东', '湖州', '溧阳', '南京南'};
            app.SilderD23 = uigauge(app.GridLayout, 'linear');
            app.SilderD23.Limits = [1 4];
            app.SilderD23.MajorTicks = [1 2 3 4];
            app.SilderD23.MajorTickLabels = {'杭州东', '湖州', '溧阳', '南京南'};
            app.SilderD24 = uigauge(app.GridLayout, 'linear');
            app.SilderD24.Limits = [1 4];
            app.SilderD24.MajorTicks = [1 2 3 4];
            app.SilderD24.MajorTickLabels = {'南京南', '溧阳', '湖州', '杭州东'};
            app.trainICONexample_5 = uiimage(app.GridLayout);
            app.trainICONexample_5.ImageSource = 'trainIcon.png';
            app.D25Label = uilabel(app.GridLayout);
            app.D25Label.Text = {'D25'; ''};
            app.SilderD25 = uigauge(app.GridLayout, 'linear');
            app.SilderD25.Limits = [1 4];
            app.SilderD25.MajorTicks = [1 2 3 4];
            app.SilderD25.MajorTickLabels = {'杭州东', '湖州', '溧阳', '南京南'};
            app.GTab = uitab(app.TabGroup);
            app.GTab.Title = '短线G';
            app.GridLayout2 = uigridlayout(app.GTab);
            app.trainICONexample_7 = uiimage(app.GridLayout2);
            app.trainICONexample_7.ImageSource = 'trainIcon.png';
            app.trainICONexample_8 = uiimage(app.GridLayout2);
            app.trainICONexample_8.ImageSource = 'trainIcon.png';
            app.G21Label_2 = uilabel(app.GridLayout2);
            app.G21Label_2.Text = 'G21';
            app.SilderG21 = uigauge(app.GridLayout2, 'linear');
            app.SilderG21.Limits = [1 4];
            app.SilderG21.MajorTicks = [1 4];
            app.SilderG21.MajorTickLabels = {'杭州东', '南京南'};
            app.SilderG21.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.SilderG23 = uigauge(app.GridLayout2, 'linear');
            app.SilderG23.Limits = [1 4];
            app.SilderG23.MajorTicks = [1 4];
            app.SilderG23.MajorTickLabels = {'杭州东', '南京南'};
            app.SilderG23.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.G23Label = uilabel(app.GridLayout2);
            app.G23Label.Text = 'G23';
            app.trainICONexample_9 = uiimage(app.GridLayout2);
            app.trainICONexample_9.ImageSource = 'trainIcon.png';
            app.G25Label = uilabel(app.GridLayout2);
            app.G25Label.Text = 'G25';
            app.SilderG25 = uigauge(app.GridLayout2, 'linear');
            app.SilderG25.Limits = [1 4];
            app.SilderG25.MajorTicks = [1 4];
            app.SilderG25.MajorTickLabels = {'杭州东', '南京南'};
            app.SilderG25.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.trainICONexample_10 = uiimage(app.GridLayout2);
            app.trainICONexample_10.ImageSource = 'trainIcon.png';
            app.G22Label = uilabel(app.GridLayout2);
            app.G22Label.Text = 'G22';
            app.SilderG22 = uigauge(app.GridLayout2, 'linear');
            app.SilderG22.Limits = [1 4];
            app.SilderG22.MajorTicks = [1 4];
            app.SilderG22.MajorTickLabels = {'南京南', '杭州东'};
            app.SilderG22.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.trainICONexample_11 = uiimage(app.GridLayout2);
            app.trainICONexample_11.ImageSource = 'trainIcon.png';
            app.G24Label = uilabel(app.GridLayout2);
            app.G24Label.Text = 'G24';
            app.SilderG24 = uigauge(app.GridLayout2, 'linear');
            app.SilderG24.Limits = [1 4];
            app.SilderG24.MajorTicks = [1 4];
            app.SilderG24.MajorTickLabels = {'南京南', '杭州东'};
            app.SilderG24.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.trainICONexample_12 = uiimage(app.GridLayout2);
            app.trainICONexample_12.ImageSource = 'trainIcon.png';
            app.G26Label_2 = uilabel(app.GridLayout2);
            app.G26Label_2.Text = 'G26';
            app.SilderG26 = uigauge(app.GridLayout2, 'linear');
            app.SilderG26.Limits = [1 4];
            app.SilderG26.MajorTicks = [1 4];
            app.SilderG26.MajorTickLabels = {'南京南', '杭州东'};
            app.SilderG26.MinorTicks = [1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3 3.1 3.2 3.3 3.4 3.5 3.6 3.7 3.8 3.9 4];
            app.DTab_2 = uitab(app.TabGroup);
            app.DTab_2.Title = '长线D';
            app.GridLayout3 = uigridlayout(app.DTab_2);
            app.trainICONexample_13 = uiimage(app.GridLayout3);
            app.trainICONexample_13.ImageSource = 'trainIcon.png';
            app.D11Label_2 = uilabel(app.GridLayout3);
            app.D11Label_2.Text = 'D11';
            app.D13Label = uilabel(app.GridLayout3);
            app.D13Label.Text = 'D13';
            app.D17Label = uilabel(app.GridLayout3);
            app.D17Label.Text = 'D17';
            app.D15Label = uilabel(app.GridLayout3);
            app.D15Label.Text = 'D15';
            app.D12Label = uilabel(app.GridLayout3);
            app.D12Label.Text = 'D12';
            app.D14Label = uilabel(app.GridLayout3);
            app.D14Label.Text = 'D14';
            app.D16Label = uilabel(app.GridLayout3);
            app.D16Label.Text = 'D16';
            app.D18Label = uilabel(app.GridLayout3);
            app.D18Label.Text = 'D18';
            app.trainICONexample_14 = uiimage(app.GridLayout3);
            app.trainICONexample_14.ImageSource = 'trainIcon.png';
            app.trainICONexample_15 = uiimage(app.GridLayout3);
            app.trainICONexample_15.ImageSource = 'trainIcon.png';
            app.trainICONexample_16 = uiimage(app.GridLayout3);
            app.trainICONexample_16.ImageSource = 'trainIcon.png';
            app.trainICONexample_17 = uiimage(app.GridLayout3);
            app.trainICONexample_17.ImageSource = 'trainIcon.png';
            app.trainICONexample_18 = uiimage(app.GridLayout3);
            app.trainICONexample_18.ImageSource = 'trainIcon.png';
            app.trainICONexample_20 = uiimage(app.GridLayout3);
            app.trainICONexample_20.ImageSource = 'trainIcon.png';
            app.trainICONexample_19 = uiimage(app.GridLayout3);
            app.trainICONexample_19.ImageSource = 'trainIcon.png';
            app.SilderD13 = uigauge(app.GridLayout3, 'linear');
            app.SilderD13.Limits = [1 6];
            app.SilderD13.MajorTicks = [1 2 3 4 5 6];
            app.SilderD13.MajorTickLabels = {'杭州东', '嘉兴南', '上海虹桥', '苏州北', '常州北', '南京南'};
            app.SilderD15 = uigauge(app.GridLayout3, 'linear');
            app.SilderD15.Limits = [1 6];
            app.SilderD15.MajorTicks = [1 2 3 4 5 6];
            app.SilderD15.MajorTickLabels = {'杭州东', '嘉兴南', '上海虹桥', '苏州北', '常州北', '南京南'};
            app.SilderD17 = uigauge(app.GridLayout3, 'linear');
            app.SilderD17.Limits = [1 6];
            app.SilderD17.MajorTicks = [1 2 3 4 5 6];
            app.SilderD17.MajorTickLabels = {'杭州东', '嘉兴南', '上海虹桥', '苏州北', '常州北', '南京南'};
            app.SilderD14 = uigauge(app.GridLayout3, 'linear');
            app.SilderD14.Limits = [1 6];
            app.SilderD14.MajorTicks = [1 2 3 4 5 6];
            app.SilderD14.MajorTickLabels = {'南京南', '常州北', '苏州北', '上海虹桥', '嘉兴南', '杭州东', ''};
            app.SilderD12 = uigauge(app.GridLayout3, 'linear');
            app.SilderD12.Limits = [1 6];
            app.SilderD12.MajorTicks = [1 2 3 4 5 6];
            app.SilderD12.MajorTickLabels = {'非站点', ' ', ' ', '上海虹桥', '嘉兴南', '杭州东', ''};
            app.SilderD11 = uigauge(app.GridLayout3, 'linear');
            app.SilderD11.Limits = [1 6];
            app.SilderD11.MajorTicks = [1 2 3 4 5 6];
            app.SilderD11.MajorTickLabels = {'非站点', ' ', ' ', '苏州北', '常州北', '南京南'};
            app.SilderD16 = uigauge(app.GridLayout3, 'linear');
            app.SilderD16.Limits = [1 6];
            app.SilderD16.MajorTicks = [1 2 3 4 5 6];
            app.SilderD16.MajorTickLabels = {'南京南', '常州北', '苏州北', '上海虹桥', '嘉兴南', '杭州东', ''};
            app.SilderD18 = uigauge(app.GridLayout3, 'linear');
            app.SilderD18.Limits = [1 6];
            app.SilderD18.MajorTicks = [1 2 3 4 5 6];
            app.SilderD18.MajorTickLabels = {'南京南', '常州北', '苏州北', '上海虹桥', '嘉兴南', '杭州东', ''};
            app.GTab_2 = uitab(app.TabGroup);
            app.GTab_2.Title = '长线G';
            app.GridLayout4 = uigridlayout(app.GTab_2);
            app.G11Label_2 = uilabel(app.GridLayout4);
            app.G11Label_2.Text = 'G11';
            app.trainICONexample_21 = uiimage(app.GridLayout4);
            app.trainICONexample_21.ImageSource = 'trainIcon.png';
            app.trainICONexample_22 = uiimage(app.GridLayout4);
            app.trainICONexample_22.ImageSource = 'trainIcon.png';
            app.G13Label_2 = uilabel(app.GridLayout4);
            app.G13Label_2.Text = 'G13';
            app.SilderG13 = uigauge(app.GridLayout4, 'linear');
            app.SilderG13.Limits = [1 6];
            app.SilderG13.MajorTicks = [1 6];
            app.SilderG13.MajorTickLabels = {'上海虹桥', '南京南'};
            app.SilderG13.MinorTicks = [];
            app.G15Label = uilabel(app.GridLayout4);
            app.G15Label.Text = 'G15';
            app.trainICONexample_23 = uiimage(app.GridLayout4);
            app.trainICONexample_23.ImageSource = 'trainIcon.png';
            app.SilderG15 = uigauge(app.GridLayout4, 'linear');
            app.SilderG15.Limits = [1 6];
            app.SilderG15.MajorTicks = [1 6];
            app.SilderG15.MajorTickLabels = {'上海虹桥', '南京南'};
            app.SilderG15.MinorTicks = [];
            app.trainICONexample_24 = uiimage(app.GridLayout4);
            app.trainICONexample_24.ImageSource = 'trainIcon.png';
            app.G12Label = uilabel(app.GridLayout4);
            app.G12Label.Text = 'G12';
            app.SilderG12 = uigauge(app.GridLayout4, 'linear');
            app.SilderG12.Limits = [1 6];
            app.SilderG12.MajorTicks = [1 6];
            app.SilderG12.MajorTickLabels = {'南京南', '上海虹桥'};
            app.SilderG12.MinorTicks = [];
            app.G14Label = uilabel(app.GridLayout4);
            app.G14Label.Text = 'G14';
            app.trainICONexample_25 = uiimage(app.GridLayout4);
            app.trainICONexample_25.ImageSource = 'trainIcon.png';
            app.trainICONexample_26 = uiimage(app.GridLayout4);
            app.trainICONexample_26.ImageSource = 'trainIcon.png';
            app.SilderG14 = uigauge(app.GridLayout4, 'linear');
            app.SilderG14.Limits = [1 6];
            app.SilderG14.MajorTicks = [1 6];
            app.SilderG14.MajorTickLabels = {'南京南', '上海虹桥'};
            app.SilderG14.MinorTicks = [];
            app.G16Label = uilabel(app.GridLayout4);
            app.G16Label.Text = 'G16';
            app.SilderG16 = uigauge(app.GridLayout4, 'linear');
            app.SilderG16.Limits = [1 6];
            app.SilderG16.MajorTicks = [1 6];
            app.SilderG16.MajorTickLabels = {'南京南', '上海虹桥'};
            app.SilderG16.MinorTicks = [];
            app.SilderG11 = uigauge(app.GridLayout4, 'linear');
            app.SilderG11.Limits = [1 6];
            app.SilderG11.MajorTicks = [1 6];
            app.SilderG11.MajorTickLabels = {'上海虹桥', '南京南'};
            app.SilderG11.MinorTicks = [];
            app.trainICONexample = uiimage(app.UIFigure);
            app.trainICONexample.ImageSource = 'trainIcon.png';
            app.TimePanel = uipanel(app.UIFigure);
            app.TimePanel.Title = 'TimePanel';
            app.Label = uilabel(app.TimePanel);
            app.Label.Text = '系统时间';
            app.Label_sysTime = uilabel(app.TimePanel);
            app.Label_sysTime.Text = {'Label_sysTime'; ''};
            app.add30mButton = uibutton(app.TimePanel, 'push');
            app.add30mButton.ButtonPushedFcn = createCallbackFcn(app, @add30mButtonPushed, true);
            app.add30mButton.Text = {'+ 30 min'; ''};
            app.add10mButton = uibutton(app.TimePanel, 'push');
            app.add10mButton.ButtonPushedFcn = createCallbackFcn(app, @add10mButtonPushed, true);
            app.add10mButton.Text = {'+ 10 min'; ''};
            app.add5mButton = uibutton(app.TimePanel, 'push');
            app.add5mButton.ButtonPushedFcn = createCallbackFcn(app, @add5mButtonPushed, true);
            app.add5mButton.Text = {'+ 5 min'; ''};
            app.Line2Gauge_4 = uigauge(app.UIFigure, 'linear');
            app.Line2Gauge_4.Limits = [1 6];
            app.Line2Gauge_4.MajorTicks = [1 6];
            app.Line2Gauge_4.MajorTickLabels = {'南京南', '上海虹桥'};
            app.Line2Gauge_4.MinorTicks = [];
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = '座位图';
            app.D21Label_3 = uilabel(app.Panel);
            app.D21Label_3.Text = 'D21';
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'oneseat.png';
            app.Label_2 = uilabel(app.Panel);
            app.Label_2.Text = '商务舱';
            app.Image_2 = uiimage(app.Panel);
            app.Image_2.ImageSource = 'oneseat.png';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '经济舱';
            app.Image_3 = uiimage(app.Panel);
            app.Image_3.ImageSource = 'oneseat.png';
            app.Image_4 = uiimage(app.Panel);
            app.Image_4.ImageSource = 'oneseat.png';
            app.Image2 = uiimage(app.Panel);
            app.Image2.ImageSource = 'arcoDesign-close@2x.png';
            app.Line2Gauge_7 = uigauge(app.UIFigure, 'linear');
            app.Line2Gauge_7.Limits = [1 6];
            app.Line2Gauge_7.MajorTicks = [1 2 3 4 5 6];
            app.Line2Gauge_7.MajorTickLabels = {'杭州东', '嘉兴南', '上海虹桥', '苏州北', '常州北', '南京南'};
            app.Line2Gauge_8 = uigauge(app.UIFigure, 'linear');
            app.Line2Gauge_8.Limits = [1 6];
            app.Line2Gauge_8.MajorTicks = [1 2 3 4 5 6];
            app.Line2Gauge_8.MajorTickLabels = {' ', ' ', ' ', '苏州北', '常州北', '南京南'};
            app.Line2Gauge_9 = uigauge(app.UIFigure, 'linear');
            app.Line2Gauge_9.Limits = [1 6];
            app.Line2Gauge_9.MajorTicks = [1 2 3 4 5 6];
            app.Line2Gauge_9.MajorTickLabels = {'南京南', '常州北', '苏州北', '上海虹桥', '嘉兴南', '杭州东', ''};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DebugApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end