classdef mainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ticket1StartStation_7   matlab.ui.control.Label
        Image8_6                matlab.ui.control.Image
        TabGroup                matlab.ui.container.TabGroup
        LaunchTab               matlab.ui.container.Tab
        LetsbookyourtrainwithjustatapLabel  matlab.ui.control.Label
        BuyTicketsButton        matlab.ui.control.Button
        Image3                  matlab.ui.control.Image
        Image2                  matlab.ui.control.Image
        QueryTab                matlab.ui.container.Tab
        isSelfTimeCheckBox      matlab.ui.control.CheckBox
        MinuteSpinner           matlab.ui.control.Spinner
        MinuteSpinnerLabel      matlab.ui.control.Label
        HourSpinner             matlab.ui.control.Spinner
        HourSpinnerLabel        matlab.ui.control.Label
        SearchButton            matlab.ui.control.Button
        SelectDestinationPanel  matlab.ui.container.Panel
        ToStationDropDown       matlab.ui.control.DropDown
        FromDropDownLabel_2     matlab.ui.control.Label
        StartStationDropDown    matlab.ui.control.DropDown
        FromDropDownLabel       matlab.ui.control.Label
        Image4                  matlab.ui.control.Image
        SearchedTicketsTab      matlab.ui.container.Tab
        TabDorT                 matlab.ui.container.TabGroup
        TabD                    matlab.ui.container.Tab
        DGrid                   matlab.ui.container.GridLayout
        Panel                   matlab.ui.container.Panel
        Button_7                matlab.ui.control.Button
        ticket1ToTime_6         matlab.ui.control.Label
        ticket1StartTime_6      matlab.ui.control.Label
        ticket1Train_6          matlab.ui.control.Label
        ticket1ToStation_6      matlab.ui.control.Label
        ticket1StartStation_6   matlab.ui.control.Label
        Image8_5                matlab.ui.control.Image
        Panel_3                 matlab.ui.container.Panel
        Button_12               matlab.ui.control.Button
        ticket1ToTime_11        matlab.ui.control.Label
        ticket1StartTime_11     matlab.ui.control.Label
        ticket1Train_11         matlab.ui.control.Label
        ticket1ToStation_11     matlab.ui.control.Label
        ticket1StartStation_11  matlab.ui.control.Label
        Image8_10               matlab.ui.control.Image
        Panel_2                 matlab.ui.container.Panel
        Button_11               matlab.ui.control.Button
        ticket1ToTime_10        matlab.ui.control.Label
        ticket1StartTime_10     matlab.ui.control.Label
        ticket1Train_10         matlab.ui.control.Label
        ticket1ToStation_10     matlab.ui.control.Label
        ticket1StartStation_10  matlab.ui.control.Label
        Image8_9                matlab.ui.control.Image
        TabT                    matlab.ui.container.Tab
        GridLayout              matlab.ui.container.GridLayout
        TTrain3                 matlab.ui.container.Panel
        Button_10               matlab.ui.control.Button
        ticket1ToTime_9         matlab.ui.control.Label
        TTrain2                 matlab.ui.container.Panel
        ticket1StartTime_9      matlab.ui.control.Label
        ticket1Train_9          matlab.ui.control.Label
        ticket1ToStation_9      matlab.ui.control.Label
        ticket1StartStation_9   matlab.ui.control.Label
        Image8_8                matlab.ui.control.Image
        Button_9                matlab.ui.control.Button
        ticket1ToTime_8         matlab.ui.control.Label
        TTrain1                 matlab.ui.container.Panel
        ticket1StartTime_8      matlab.ui.control.Label
        ticket1Train_8          matlab.ui.control.Label
        ticket1ToStation_8      matlab.ui.control.Label
        ticket1StartStation_8   matlab.ui.control.Label
        Image8_7                matlab.ui.control.Image
        Button_8                matlab.ui.control.Button
        ticket1ToTime_7         matlab.ui.control.Label
        ticket1StartTime_7      matlab.ui.control.Label
        ticket1Train_7          matlab.ui.control.Label
        ticket1ToStation_7      matlab.ui.control.Label
        Tickets_Stop_station    matlab.ui.control.Label
        Tickets_Start_station   matlab.ui.control.Label
        Image7                  matlab.ui.control.Image
        BackBtnInTicket         matlab.ui.control.Button
        Image5                  matlab.ui.control.Image
        BookTab                 matlab.ui.container.Tab
        ticket1ToTime_2         matlab.ui.control.Label
        ticket1StartTime_2      matlab.ui.control.Label
        ticket1Train_2          matlab.ui.control.Label
        ticket1ToStation_2      matlab.ui.control.Label
        ticket1StartStation_2   matlab.ui.control.Label
        BookBtn                 matlab.ui.control.Button
        BackBtnInBook           matlab.ui.control.Button
        Image6                  matlab.ui.control.Image
    end

    
    properties (Access = public)
        trainDispatch % Description
    end
    
    properties (Access = private)

    end
    
    methods (Access = public)
        function showTickets(app,tickets)
            startStation=app.StartStationDropDown.Value;
            stopStation=app.ToStationDropDown.Value;

            dIndex=1;
            tIndex=1;
            
            if tickets.length==0
                "找不到任何车票！！！"
            end
            if tickets.length>=1
                for i=1:tickets(1).length
                    trains=tickets(i);
                    if contains(trains, ',')
                        "含有转乘"
                    else
                        "直达列车"
                        startTime=app.trainDispatch.findTrain(trains).getStationDepartureTime(Station(startStation));
                        ToTime=app.trainDispatch.findTrain(trains).getStationArrrivalTime(Station(stopStation));
                        app.writeTicketPanel(1,dIndex,startStation,startTime,stopStation,ToTime,trains);
                        dIndex=dIndex+1;
                    end
                    

                end


            end 
        end

        function writeTicketPanel(app,DorT,index,startStation,startTime,ToStation,ToTime,trainCode)
            if DorT==1
                "直达"
                switch index
                    case 1
                        app.ticket1Train_10.Text=trainCode;
                        app.ticket1StartStation_10.Text=startStation;
                        app.ticket1StartTime_10.Text=datestr(startTime, 'HH:MM');
                        app.ticket1ToStation_10.Text=ToStation;
                        app.ticket1ToTime_10.Text=datestr(ToTime, 'HH:MM');


                end

            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: BuyTicketsButton
        function BuyTicketsButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.QueryTab;
        end

        % Callback function
        function DebugButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.debugTab;
        end

        % Button pushed function: SearchButton
        function SearchButtonPushed(app, event)
            startStation=Station(app.StartStationDropDown.Value);
            if ~app.isSelfTimeCheckBox.Value
                startStation.arrivalTime=app.trainDispatch.SysTime;
            else
                timestr=sprintf('%02d:%02d:00', app.HourSpinner.Value, app.MinuteSpinner.Value);
                startStation.arrivalTime=datetime(timestr);
            end
            
            toStation=Station(app.ToStationDropDown.Value);
            SearchedTickets=app.trainDispatch.findAvailableTickets(startStation,toStation,0);
            "搜索到的车票为"
%             disp(SearchedTickets)
            tickets=strsplit(SearchedTickets,"-");
%             disp(tickets(1:end-1))
            app.showTickets(tickets(1:end-1));
            app.TabGroup.SelectedTab = app.SearchedTicketsTab;
        end

        % Button pushed function: BackBtnInTicket
        function BackBtnInTicketPushed(app, event)
            app.TabGroup.SelectedTab = app.QueryTab;
        end

        % Button pushed function: BackBtnInBook
        function BackBtnInBookButtonPushed(app, event)
            app.TabGroup.SelectedTab = app.SearchedTicketsTab;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.LaunchTab = uitab(app.TabGroup);
            app.LaunchTab.Title = '初始界面';
            app.Image2 = uiimage(app.LaunchTab);
            app.Image2.ImageSource = 'launch-bg-norad.png';
            app.Image3 = uiimage(app.LaunchTab);
            app.Image3.ImageSource = 'launch-bg-gradient-black.png';
            app.BuyTicketsButton = uibutton(app.LaunchTab, 'push');
            app.BuyTicketsButton.ButtonPushedFcn = createCallbackFcn(app, @BuyTicketsButtonPushed, true);
            app.BuyTicketsButton.Text = 'Buy  Tickets';
            app.LetsbookyourtrainwithjustatapLabel = uilabel(app.LaunchTab);
            app.LetsbookyourtrainwithjustatapLabel.Text = {'Let''s book your train'; 'with just a tap'; ''};
            app.QueryTab = uitab(app.TabGroup);
            app.QueryTab.Title = '查询界面';
            app.Image4 = uiimage(app.QueryTab);
            app.Image4.ImageSource = 'query-bg1.png';
            app.SelectDestinationPanel = uipanel(app.QueryTab);
            app.SelectDestinationPanel.Title = 'Select Destination';
            app.FromDropDownLabel = uilabel(app.SelectDestinationPanel);
            app.FromDropDownLabel.Text = 'From';
            app.StartStationDropDown = uidropdown(app.SelectDestinationPanel);
            app.StartStationDropDown.Items = {'南京南', '常州北', '苏州北', '上海虹桥', '嘉兴南', '溧阳', '湖州', '杭州东'};
            app.StartStationDropDown.Value = '南京南';
            app.FromDropDownLabel_2 = uilabel(app.SelectDestinationPanel);
            app.FromDropDownLabel_2.Text = 'To';
            app.ToStationDropDown = uidropdown(app.SelectDestinationPanel);
            app.ToStationDropDown.Items = {'南京南', '常州北', '苏州北', '上海虹桥', '嘉兴南', '溧阳', '湖州', '杭州东'};
            app.ToStationDropDown.Value = '溧阳';
            app.SearchButton = uibutton(app.QueryTab, 'push');
            app.SearchButton.ButtonPushedFcn = createCallbackFcn(app, @SearchButtonPushed, true);
            app.SearchButton.Text = 'Search';
            app.HourSpinnerLabel = uilabel(app.QueryTab);
            app.HourSpinnerLabel.Text = 'Hour';
            app.HourSpinner = uispinner(app.QueryTab);
            app.HourSpinner.Value = 10;
            app.MinuteSpinnerLabel = uilabel(app.QueryTab);
            app.MinuteSpinnerLabel.Text = 'Minute';
            app.MinuteSpinner = uispinner(app.QueryTab);
            app.isSelfTimeCheckBox = uicheckbox(app.QueryTab);
            app.isSelfTimeCheckBox.Text = '自定义时间';
            app.SearchedTicketsTab = uitab(app.TabGroup);
            app.SearchedTicketsTab.Title = '车票界面';
            app.Image5 = uiimage(app.SearchedTicketsTab);
            app.Image5.ImageSource = 'tickets_bg.png';
            app.BackBtnInTicket = uibutton(app.SearchedTicketsTab, 'push');
            app.BackBtnInTicket.ButtonPushedFcn = createCallbackFcn(app, @BackBtnInTicketPushed, true);
            app.BackBtnInTicket.Text = {'返回'; ''};
            app.Image7 = uiimage(app.SearchedTicketsTab);
            app.Image7.Visible = 'off';
            app.Image7.ImageSource = '图片 3@1x.png';
            app.Tickets_Start_station = uilabel(app.SearchedTicketsTab);
            app.Tickets_Start_station.Text = '起始站';
            app.Tickets_Stop_station = uilabel(app.SearchedTicketsTab);
            app.Tickets_Stop_station.Text = '终点站';
            app.TabDorT = uitabgroup(app.SearchedTicketsTab);
            app.TabD = uitab(app.TabDorT);
            app.TabD.Title = '直达班车';
            app.DGrid = uigridlayout(app.TabD);
            app.Panel_2 = uipanel(app.DGrid);
            app.Image8_9 = uiimage(app.Panel_2);
            app.Image8_9.ImageSource = 'ticket.png';
            app.ticket1StartStation_10 = uilabel(app.Panel_2);
            app.ticket1ToStation_10 = uilabel(app.Panel_2);
            app.ticket1Train_10 = uilabel(app.Panel_2);
            app.ticket1StartTime_10 = uilabel(app.Panel_2);
            app.ticket1StartTime_10.Text = 'Label2';
            app.ticket1ToTime_10 = uilabel(app.Panel_2);
            app.ticket1ToTime_10.Text = 'Label2';
            app.Button_11 = uibutton(app.Panel_2, 'push');
            app.Button_11.Text = '订票';
            app.Panel_3 = uipanel(app.DGrid);
            app.Image8_10 = uiimage(app.Panel_3);
            app.Image8_10.ImageSource = 'ticket.png';
            app.ticket1StartStation_11 = uilabel(app.Panel_3);
            app.ticket1ToStation_11 = uilabel(app.Panel_3);
            app.ticket1Train_11 = uilabel(app.Panel_3);
            app.ticket1StartTime_11 = uilabel(app.Panel_3);
            app.ticket1StartTime_11.Text = 'Label2';
            app.ticket1ToTime_11 = uilabel(app.Panel_3);
            app.ticket1ToTime_11.Text = 'Label2';
            app.Button_12 = uibutton(app.Panel_3, 'push');
            app.Button_12.Text = '订票';
            app.Panel = uipanel(app.DGrid);
            app.Image8_5 = uiimage(app.Panel);
            app.Image8_5.ImageSource = 'ticket.png';
            app.ticket1StartStation_6 = uilabel(app.Panel);
            app.ticket1ToStation_6 = uilabel(app.Panel);
            app.ticket1Train_6 = uilabel(app.Panel);
            app.ticket1StartTime_6 = uilabel(app.Panel);
            app.ticket1StartTime_6.Text = 'Label2';
            app.ticket1ToTime_6 = uilabel(app.Panel);
            app.ticket1ToTime_6.Text = 'Label2';
            app.Button_7 = uibutton(app.Panel, 'push');
            app.Button_7.Text = '订票';
            app.TabT = uitab(app.TabDorT);
            app.TabT.Title = '转乘班车';
            app.GridLayout = uigridlayout(app.TabT);
            app.TTrain1 = uipanel(app.GridLayout);
            app.Image8_6 = uiimage(app.TTrain1);
            app.Image8_6.ImageSource = 'ticket.png';
            app.ticket1StartStation_7 = uilabel(app.TTrain1);
            app.ticket1ToStation_7 = uilabel(app.TTrain1);
            app.ticket1Train_7 = uilabel(app.TTrain1);
            app.ticket1StartTime_7 = uilabel(app.TTrain1);
            app.ticket1StartTime_7.Text = 'Label2';
            app.ticket1ToTime_7 = uilabel(app.TTrain1);
            app.ticket1ToTime_7.Text = 'Label2';
            app.Button_8 = uibutton(app.TTrain1, 'push');
            app.Button_8.Text = '订票';
            app.TTrain2 = uipanel(app.GridLayout);
            app.Image8_7 = uiimage(app.TTrain2);
            app.Image8_7.ImageSource = 'ticket.png';
            app.ticket1StartStation_8 = uilabel(app.TTrain2);
            app.ticket1ToStation_8 = uilabel(app.TTrain2);
            app.ticket1Train_8 = uilabel(app.TTrain2);
            app.ticket1StartTime_8 = uilabel(app.TTrain2);
            app.ticket1StartTime_8.Text = 'Label2';
            app.ticket1ToTime_8 = uilabel(app.TTrain2);
            app.ticket1ToTime_8.Text = 'Label2';
            app.Button_9 = uibutton(app.TTrain2, 'push');
            app.Button_9.Text = '订票';
            app.TTrain3 = uipanel(app.GridLayout);
            app.Image8_8 = uiimage(app.TTrain3);
            app.Image8_8.ImageSource = 'ticket.png';
            app.ticket1StartStation_9 = uilabel(app.TTrain3);
            app.ticket1ToStation_9 = uilabel(app.TTrain3);
            app.ticket1Train_9 = uilabel(app.TTrain3);
            app.ticket1StartTime_9 = uilabel(app.TTrain3);
            app.ticket1StartTime_9.Text = 'Label2';
            app.ticket1ToTime_9 = uilabel(app.TTrain3);
            app.ticket1ToTime_9.Text = 'Label2';
            app.Button_10 = uibutton(app.TTrain3, 'push');
            app.Button_10.Text = '订票';
            app.BookTab = uitab(app.TabGroup);
            app.BookTab.Title = '订票界面';
            app.Image6 = uiimage(app.BookTab);
            app.Image6.ImageSource = 'book-bg-2.png';
            app.BackBtnInBook = uibutton(app.BookTab, 'push');
            app.BackBtnInBook.ButtonPushedFcn = createCallbackFcn(app, @BackBtnInBookButtonPushed, true);
            app.BackBtnInBook.Text = {'返回'; ''};
            app.BookBtn = uibutton(app.BookTab, 'push');
            app.BookBtn.Text = '订票';
            app.ticket1StartStation_2 = uilabel(app.BookTab);
            app.ticket1ToStation_2 = uilabel(app.BookTab);
            app.ticket1Train_2 = uilabel(app.BookTab);
            app.ticket1StartTime_2 = uilabel(app.BookTab);
            app.ticket1StartTime_2.Text = 'Label2';
            app.ticket1ToTime_2 = uilabel(app.BookTab);
            app.ticket1ToTime_2.Text = 'Label2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainApp

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end